classdef Cal_Per_Ar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SolopongaundatoLabel     matlab.ui.control.Label
        txtr                     matlab.ui.control.NumericEditField
        ResultadoEditFieldLabel  matlab.ui.control.Label
        CirculoButton            matlab.ui.control.Button
        PermetroLabel            matlab.ui.control.Label
        RectnguloButton_2        matlab.ui.control.Button
        reaLabel                 matlab.ui.control.Label
        txt2                     matlab.ui.control.NumericEditField
        AlturaLabel              matlab.ui.control.Label
        RectnguloButton          matlab.ui.control.Button
        txt1                     matlab.ui.control.NumericEditField
        BaseLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RectnguloButton
        function RectnguloButtonPushed(app, event)
            numero1=app.txt1.Value
            numero2=app.txt2.Value
            app.txtr.Value=area_rectangulo(numero1,numero2)           
        end

        % Button pushed function: CirculoButton
        function CirculoButtonPushed(app, event)
            numero1=app.txt1.Value
            app.txtr.Value=area_circulo(numero1)   
        end

        % Button pushed function: RectnguloButton_2
        function RectnguloButton_2Pushed(app, event)
            numero1=app.txt1.Value
            numero2=app.txt2.Value
            app.txtr.Value=perimetro_rectangulo(numero1,numero2)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base/Área';
            app.txt1 = uieditfield(app.UIFigure, 'numeric');
            app.RectnguloButton = uibutton(app.UIFigure, 'push');
            app.RectnguloButton.ButtonPushedFcn = createCallbackFcn(app, @RectnguloButtonPushed, true);
            app.RectnguloButton.Text = 'Rectángulo';
            app.AlturaLabel = uilabel(app.UIFigure);
            app.AlturaLabel.Text = 'Altura';
            app.txt2 = uieditfield(app.UIFigure, 'numeric');
            app.reaLabel = uilabel(app.UIFigure);
            app.reaLabel.Text = 'Área ';
            app.RectnguloButton_2 = uibutton(app.UIFigure, 'push');
            app.RectnguloButton_2.ButtonPushedFcn = createCallbackFcn(app, @RectnguloButton_2Pushed, true);
            app.RectnguloButton_2.Text = 'Rectángulo';
            app.PermetroLabel = uilabel(app.UIFigure);
            app.PermetroLabel.Text = 'Perímetro';
            app.CirculoButton = uibutton(app.UIFigure, 'push');
            app.CirculoButton.ButtonPushedFcn = createCallbackFcn(app, @CirculoButtonPushed, true);
            app.CirculoButton.Text = 'Circulo ';
            app.ResultadoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.txtr = uieditfield(app.UIFigure, 'numeric');
            app.SolopongaundatoLabel = uilabel(app.UIFigure);
            app.SolopongaundatoLabel.Text = 'Solo ponga un dato ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cal_Per_Ar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end